classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        UploadMenu                      matlab.ui.container.Menu
        AlphaKnobLabel                  matlab.ui.control.Label
        AlphaKnob                       matlab.ui.control.Knob
        SigmaKnobLabel                  matlab.ui.control.Label
        SigmaKnob                       matlab.ui.control.Knob
        SharpnessstrengthKnobLabel      matlab.ui.control.Label
        SharpnessstrengthKnob           matlab.ui.control.Knob
        UIAxes                          matlab.ui.control.UIAxes
        GammaKnobLabel                  matlab.ui.control.Label
        GammaKnob                       matlab.ui.control.Knob
        ContrastLabel                   matlab.ui.control.Label
        LowInSliderLabel                matlab.ui.control.Label
        LowInSlider                     matlab.ui.control.Slider
        HighInSliderLabel               matlab.ui.control.Label
        HighInSlider                    matlab.ui.control.Slider
        UIAxes3                         matlab.ui.control.UIAxes
        autoadjustButton                matlab.ui.control.Button
        originalButton                  matlab.ui.control.Button
        BrightnessKnobLabel             matlab.ui.control.Label
        BrightnessKnob                  matlab.ui.control.Knob
        RadiusSliderLabel               matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        SharpnessLabel                  matlab.ui.control.Label
        VideoformatssupportedDropDownLabel  matlab.ui.control.Label
        VideoformatssupportedDropDown   matlab.ui.control.DropDown
        VideofilesDropDownLabel         matlab.ui.control.Label
        VideofilesDropDown              matlab.ui.control.DropDown
        ChoosevideofileORLabel          matlab.ui.control.Label
        ExecuteBasicSegmentationButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ChooseimagefileButton           matlab.ui.control.Button
        VideoseperationButton           matlab.ui.control.Button
        Button                          matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        TuftcolorButtonGroup            matlab.ui.container.ButtonGroup
        BrightButton                    matlab.ui.control.RadioButton
        DarkButton                      matlab.ui.control.RadioButton
        MaxSpinnerLabel                 matlab.ui.control.Label
        MaxSpinner                      matlab.ui.control.Spinner
        MinSpinnerLabel                 matlab.ui.control.Label
        MinSpinner                      matlab.ui.control.Spinner
        NoofpixelsineachsegmentLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        autoUpdate % Auto_upda
        Contrast   % Image Contrast
    end
    
    properties (Access = public)
        Mask                    %Mask
        Image                   % Image
        OriginalImage           %Original Image
        ColorImage              %Colored image
        OriginalVideo           % Original video data
        CroppedImage            %cropped image
        OriginalCroppedImage    % Original cropped image
        CropFrame               %the frame of the crop
        CroppedMask             %Mask with the croping
        SegmentedImage          %Segmented Image
        NotToUpdate
    end
    
    methods (Access = private)
        %         Thing to add:
        %         1.choosing a picture
        %         2.basic segmentation
        %         2.1 choose an area in which only a tuft exist, we dont let eny segmentation bugger than size of tuft*2 and smaller tuft/4
        %         3.flow diraction choose
        %         4.make for each segmentetion part - a main point
        %         5.algorithm to understand how attached is the flow
        %         6.visualize with color, how much it is connected
        %         7. visualize streamlines
        %         8.line to show the diffrance between connected to not connected area
        %         9.bar to see the cohirance?
        
        function autoupdate(app)
            if app.NotToUpdate
                app.NotToUpdate=0;
            else
                update_image(app);
            end

            imshow(app.CroppedImage,'Parent',app.UIAxes);
            contrast_histogram=imhist(app.CroppedImage,2000);
            histogram(app.UIAxes3,contrast_histogram,2000);
            if ~isempty(app.SegmentedImage)
                app.SegmentedImage = matlab_seg(app.CroppedImage,app);
                imshow(app.SegmentedImage,'Parent',app.UIAxes2);
            end
            'Updated'
            
        end
        
        
        function show_image_correction_menu(app)
            app.ChoosevideofileORLabel.Visible='off';
            app.VideofilesDropDownLabel.Visible='off'; app.VideoformatssupportedDropDownLabel.Visible='off';
            app.VideofilesDropDown.Visible='off'; app.VideoformatssupportedDropDown.Visible='off';
            app.Button.Visible='off';app.ChooseimagefileButton.Visible='off';
        end
        
        function cropimage(app)
            figure('Name','Croping','NumberTitle','off','Color',[224/256 247/256 250/256]);
            hold on
            title('Crop the tuft area with a polygon crop','FontSize',16,'Color',[0,0,0]);
            app.Mask = croping(app.OriginalImage);
            hold off
            close
            [coloms,rows]=size(app.Mask);
            %find the boundries of the mask
            leftColumn =[];rightColumn=[];uprow=[];downrow=[];
            i=1; stop_cr=[];
            while isempty(stop_cr)
                leftColumn = app.Mask(:, i); % Get column i
                % Find where it's non-zero:
                stop_cr = find(leftColumn <1, 1, 'first');
                i=i+1;
            end
            leftColumn=i;
            i=rows; stop_cr=[];
            while isempty(stop_cr)
                rightColumn = app.Mask(:, i); % Get column i
                % Find where it's non-zero:
                stop_cr = find(rightColumn <1, 1, 'first');
                i=i-1;
            end
            rightColumn=i;
            i=1; stop_cr=[];
            while isempty(stop_cr)
                uprow = app.Mask(i, :); % Get row i
                % Find where it's non-zero:
                stop_cr = find(uprow <1, 1, 'first');
                i=i+1;
            end
            uprow=i;
            i=coloms; stop_cr=[];
            while isempty(stop_cr)
                downrow = app.Mask(i, :); % Get row i
                % Find where it's non-zero:
                stop_cr = find(downrow <1, 1, 'first');
                i=i-1;
            end
            downrow=i;
            app.Image=rgb2gray(app.Image);
            app.Image(app.Mask)=0;
            app.CropFrame=[leftColumn, uprow, rightColumn-leftColumn+1, downrow-uprow+1];
            app.OriginalCroppedImage= imcrop(app.Image,app.CropFrame);
            app.CroppedMask= imcrop(app.Mask,app.CropFrame);
            app.CroppedImage=app.OriginalCroppedImage;
        end
        
        function update_image(app)
            I=app.OriginalCroppedImage;
            
            I(:,:,:)=I(:,:,:)*(50^log((app.BrightnessKnob.Value+0.5)));
            try
                I= locallapfilt(I, app.SigmaKnob.Value, app.AlphaKnob.Value);
            end
                I = imadjust(I,[app.LowInSlider.Value app.HighInSlider.Value],[],app.GammaKnob.Value);
                app.CroppedImage=imsharpen(I,'Radius',app.RadiusSlider.Value,'Amount',app.SharpnessstrengthKnob.Value,'Threshold',app.ThresholdSlider.Value);
        end
        
        function video_format_choose(app)
            di=dir; count=2; videos(1)= string('choose...');
            for i=1:length(di)
                k=strfind(di(i).name,app.VideoformatssupportedDropDown.Value);
                if ~isempty(k)
                    videos(count)=string(di(i).name);
                    count=count+1;
                end
            end
            if exist('videos')
                if ~isempty(videos)
                    app.VideofilesDropDown.Items=videos;
                end
            end
            clear videos
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %detect which video file formats the user system supports
            s=VideoReader.getFileFormats;
            for i=1:length(s)
                ms(i)=string(s(1,i).Extension);
            end
            app.VideoformatssupportedDropDown.Items=ms;
            app.VideofilesDropDown.Items = {'none'};
            app.NotToUpdate=0;            
        end

        % Value changed function: AlphaKnob
        function AlphaKnobValueChanged(app, event)
            Alpha = app.AlphaKnob.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
        end

        % Value changed function: SigmaKnob
        function SigmaKnobValueChanged(app, event)
            Sigma = app.SigmaKnob.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
            
        end

        % Value changed function: SharpnessstrengthKnob
        function SharpnessstrengthKnobValueChanged(app, event)
            Sharp = app.SharpnessstrengthKnob.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
            
        end

        % Value changed function: GammaKnob
        function GammaKnobValueChanged(app, event)
            Gamma = app.GammaKnob.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: LowInSlider
        function LowInSliderValueChanged(app, event)
            Low_In = app.LowInSlider.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: HighInSlider
        function HighInSliderValueChanged(app, event)
            High_In = app.HighInSlider.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Button pushed function: autoadjustButton
        function autoadjustButtonPushed(app, event)
            app.CroppedImage = imadjust(app.CroppedImage);
            app.NotToUpdate=1;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Button pushed function: originalButton
        function originalButtonPushed(app, event)
            app.CroppedImage =app.OriginalCroppedImage;
            app.NotToUpdate=1;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: BrightnessKnob
        function BrightnessKnobValueChanged(app, event)
            Brightness = app.BrightnessKnob.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            Radius = app.RadiusSlider.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            Threshold = app.ThresholdSlider.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: VideoformatssupportedDropDown
        function VideoformatssupportedDropDownValueChanged(app, event)
            VideoformatssupportedDropDownValue = app.VideoformatssupportedDropDown.Value;
            app.VideofilesDropDown.Items = {'none'};
            %detect which videos from the chosen format are in the file
            video_format_choose(app)
        end

        % Value changed function: VideofilesDropDown
        function VideofilesDropDownValueChanged(app, event)
            VideofilesDropDownValue = app.VideofilesDropDown.Value;
            
            if app.VideofilesDropDown.Value~= string('none')
                app.OriginalVideo=VideoReader(app.VideofilesDropDown.Value)
                app.Image = read(app.OriginalVideo, 1);
                app.OriginalImage = rgb2gray(app.Image);
                cropimage(app)
                show_image_correction_menu(app)
                imshow(app.CroppedImage,'Parent',app.UIAxes);
                app.ColorImage = app.Image;
                app.Image=app.OriginalImage;
                app.autoUpdate = 1 ;
                contrast_histogram=imhist(app.CroppedImage,2000)';
                histogram(app.UIAxes3,contrast_histogram,2000);
                app.VideoseperationButton.Visible = 'on';
            end
        end

        % Button pushed function: ExecuteBasicSegmentationButton
        function ExecuteBasicSegmentationButtonPushed(app, event)
            app.SegmentedImage = matlab_seg(app.CroppedImage,app);
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
            app.ExecuteBasicSegmentationButton.Visible='off';
        end

        % Button pushed function: ChooseimagefileButton
        function ChooseimagefileButtonPushed(app, event)
            
            app.Image.temp=uiimport('-file');
            a=string(fieldnames(app.Image.temp));
            if length(a)==1%jpg
                app.Image=getfield(app.Image.temp,a);
                app.OriginalImage = rgb2gray(app.Image);
            else %png
                index=getfield(app.Image.temp,a(1));
                map=getfield(app.Image.temp,a(2));
                app.Image = ind2rgb(index,map);
                app.OriginalImage = ind2gray(index,map);
            end
            cropimage(app)
            show_image_correction_menu(app)
            imshow(app.CroppedImage,'Parent',app.UIAxes);
            app.ColorImage = app.Image;
            app.Image=app.OriginalImage;
            app.autoUpdate = 1 ;
            contrast_histogram=imhist(app.CroppedImage,2000)';
            histogram(app.UIAxes3,contrast_histogram,2000);
        end

        % Button pushed function: VideoseperationButton
        function VideoseperationButtonPushed(app, event)
            segnetationvideoseperation(app)

        end

        % Selection changed function: TuftcolorButtonGroup
        function TuftcolorButtonGroupSelectionChanged(app, event)
            selectedButton = app.TuftcolorButtonGroup.SelectedObject;
            app.SegmentedImage = matlab_seg(app.CroppedImage,app);
            imshow(app.SegmentedImage,'Parent',app.UIAxes2);
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            value = app.MaxSpinner.Value;
            app.SegmentedImage = matlab_seg(app.CroppedImage,app);
            imshow(app.SegmentedImage,'Parent',app.UIAxes2);
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            value = app.MinSpinner.Value;
            app.SegmentedImage = matlab_seg(app.CroppedImage,app);
            imshow(app.SegmentedImage,'Parent',app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UploadMenu = uimenu(app.Menu);
            app.UploadMenu.Text = 'Upload ';
            app.AlphaKnobLabel = uilabel(app.UIFigure);
            app.AlphaKnobLabel.Text = 'Alpha';
            app.AlphaKnob = uiknob(app.UIFigure, 'continuous');
            app.AlphaKnob.Limits = [1e-10 1];
            app.AlphaKnob.MajorTicks = [1e-10 0.1000000001 0.2000000001 0.3000000001 0.4000000001 0.5000000001 0.6000000001 0.7000000001 0.8000000001 0.9000000001 1];
            app.AlphaKnob.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.AlphaKnob.ValueChangedFcn = createCallbackFcn(app, @AlphaKnobValueChanged, true);
            app.AlphaKnob.Value = 0.1;
            app.SigmaKnobLabel = uilabel(app.UIFigure);
            app.SigmaKnobLabel.Text = 'Sigma';
            app.SigmaKnob = uiknob(app.UIFigure, 'continuous');
            app.SigmaKnob.Limits = [1e-10 1];
            app.SigmaKnob.MajorTicks = [1e-10 0.1000000001 0.2000000001 0.3000000001 0.4000000001 0.5000000001 0.6000000001 0.7000000001 0.8000000001 0.9000000001 1];
            app.SigmaKnob.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.SigmaKnob.ValueChangedFcn = createCallbackFcn(app, @SigmaKnobValueChanged, true);
            app.SigmaKnob.Value = 0.1;
            app.SharpnessstrengthKnobLabel = uilabel(app.UIFigure);
            app.SharpnessstrengthKnobLabel.Text = {'Sharpness'; 'strength'};
            app.SharpnessstrengthKnob = uiknob(app.UIFigure, 'continuous');
            app.SharpnessstrengthKnob.Limits = [0 10];
            app.SharpnessstrengthKnob.ValueChangedFcn = createCallbackFcn(app, @SharpnessstrengthKnobValueChanged, true);
            app.SharpnessstrengthKnob.Value = 0.8;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.GammaKnobLabel = uilabel(app.UIFigure);
            app.GammaKnobLabel.Text = 'Gamma';
            app.GammaKnob = uiknob(app.UIFigure, 'continuous');
            app.GammaKnob.Limits = [0.1 1.9];
            app.GammaKnob.ValueChangedFcn = createCallbackFcn(app, @GammaKnobValueChanged, true);
            app.GammaKnob.Value = 1;
            app.ContrastLabel = uilabel(app.UIFigure);
            app.ContrastLabel.Text = 'Contrast';
            app.LowInSliderLabel = uilabel(app.UIFigure);
            app.LowInSliderLabel.Text = 'Low In';
            app.LowInSlider = uislider(app.UIFigure);
            app.LowInSlider.Limits = [0 1];
            app.LowInSlider.ValueChangedFcn = createCallbackFcn(app, @LowInSliderValueChanged, true);
            app.HighInSliderLabel = uilabel(app.UIFigure);
            app.HighInSliderLabel.Text = 'High In';
            app.HighInSlider = uislider(app.UIFigure);
            app.HighInSlider.Limits = [0 1];
            app.HighInSlider.ValueChangedFcn = createCallbackFcn(app, @HighInSliderValueChanged, true);
            app.HighInSlider.Value = 1;
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.autoadjustButton = uibutton(app.UIFigure, 'push');
            app.autoadjustButton.ButtonPushedFcn = createCallbackFcn(app, @autoadjustButtonPushed, true);
            app.autoadjustButton.Text = 'auto adjust';
            app.originalButton = uibutton(app.UIFigure, 'push');
            app.originalButton.ButtonPushedFcn = createCallbackFcn(app, @originalButtonPushed, true);
            app.originalButton.Text = 'original';
            app.BrightnessKnobLabel = uilabel(app.UIFigure);
            app.BrightnessKnobLabel.Text = 'Brightness';
            app.BrightnessKnob = uiknob(app.UIFigure, 'continuous');
            app.BrightnessKnob.Limits = [0 2];
            app.BrightnessKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.BrightnessKnob.ValueChangedFcn = createCallbackFcn(app, @BrightnessKnobValueChanged, true);
            app.BrightnessKnob.Value = 0.5;
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [0 20];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.Value = 1;
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.SharpnessLabel = uilabel(app.UIFigure);
            app.SharpnessLabel.Text = 'Sharpness';
            app.VideoformatssupportedDropDownLabel = uilabel(app.UIFigure);
            app.VideoformatssupportedDropDownLabel.Text = 'Video formats supported';
            app.VideoformatssupportedDropDown = uidropdown(app.UIFigure);
            app.VideoformatssupportedDropDown.Items = {'1', 'Option 2', '2', 'Option 4'};
            app.VideoformatssupportedDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoformatssupportedDropDownValueChanged, true);
            app.VideoformatssupportedDropDown.Value = '1';
            app.VideofilesDropDownLabel = uilabel(app.UIFigure);
            app.VideofilesDropDownLabel.Text = 'Video files';
            app.VideofilesDropDown = uidropdown(app.UIFigure);
            app.VideofilesDropDown.ValueChangedFcn = createCallbackFcn(app, @VideofilesDropDownValueChanged, true);
            app.ChoosevideofileORLabel = uilabel(app.UIFigure);
            app.ChoosevideofileORLabel.Text = {'Choose video file'; ''; ''; ''; ''; ''; ''; ''; ''; ''; '            OR'};
            app.ExecuteBasicSegmentationButton = uibutton(app.UIFigure, 'push');
            app.ExecuteBasicSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteBasicSegmentationButtonPushed, true);
            app.ExecuteBasicSegmentationButton.Text = {'Execute'; 'Basic'; 'Segmentation'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ChooseimagefileButton = uibutton(app.UIFigure, 'push');
            app.ChooseimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseimagefileButtonPushed, true);
            app.ChooseimagefileButton.Text = 'Choose image file';
            app.VideoseperationButton = uibutton(app.UIFigure, 'push');
            app.VideoseperationButton.ButtonPushedFcn = createCallbackFcn(app, @VideoseperationButtonPushed, true);
            app.VideoseperationButton.Visible = 'off';
            app.VideoseperationButton.Text = {'Video'; 'seperation'};
            app.Button = uitextarea(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Visible = 'off';
            app.TuftcolorButtonGroup = uibuttongroup(app.UIFigure);
            app.TuftcolorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TuftcolorButtonGroupSelectionChanged, true);
            app.TuftcolorButtonGroup.Title = 'Tuft color';
            app.BrightButton = uiradiobutton(app.TuftcolorButtonGroup);
            app.BrightButton.Text = 'Bright';
            app.BrightButton.Value = true;
            app.DarkButton = uiradiobutton(app.TuftcolorButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.MaxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.UIFigure);
            app.MaxSpinner.Limits = [0 Inf];
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 80;
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.Limits = [0 Inf];
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinner.Value = 15;
            app.NoofpixelsineachsegmentLabel = uilabel(app.UIFigure);
            app.NoofpixelsineachsegmentLabel.Text = {'No. of pixels '; 'in each segment'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end