classdef mainiii < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        MaxpixelsizeSpinnerLabel  matlab.ui.control.Label
        MaxpixelsizeSpinner       matlab.ui.control.Spinner
        MinpixelsizeSpinnerLabel  matlab.ui.control.Label
        MinpixelsizeSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        autoUpdate % Auto_upda
    end

    properties (Access = public)
        Mask       %Mask
        Image      % Image
        OriginalImage %Original Image
        ColorImage %Colored image
        Originalvideo % Original video data
    end
    
    methods (Access = private)
        
        function results = autoupdate(app)
            app.OriginalImage(app.Mask)=256;
            background = imopen(app.OriginalImage,strel('disk',15));
            surf(double(background(1:8:end,1:8:end))),zlim([0 255]);
            ax = gca;
            ax.YDir = 'reverse';
            % Display the Background Approximation as a Surface
            I2 = app.OriginalImage - background;
            I3 = imadjust(I2);
            bw = imbinarize(I3); %binerize the image
            bw = bwareafilt(bw, [app.MinpixelsizeSpinner.Value app.MaxpixelsizeSpinner.Value]);%remove items not with [min max] pixels
            imshow(bw,'Parent',app.UIAxes);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.autoUpdate = 1 ;
            
        end

        % Value changed function: MinpixelsizeSpinner
        function MinpixelsizeSpinnerValueChanged(app, event)
            value = app.MinpixelsizeSpinner.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end

        % Value changed function: MaxpixelsizeSpinner
        function MaxpixelsizeSpinnerValueChanged(app, event)
            value = app.MaxpixelsizeSpinner.Value;
            if app.autoUpdate
                'Updating'
                autoupdate(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.MaxpixelsizeSpinnerLabel = uilabel(app.UIFigure);
            app.MaxpixelsizeSpinnerLabel.Text = 'Max pixel size';
            app.MaxpixelsizeSpinner = uispinner(app.UIFigure);
            app.MaxpixelsizeSpinner.Limits = [2 5000];
            app.MaxpixelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxpixelsizeSpinnerValueChanged, true);
            app.MaxpixelsizeSpinner.Value = 80;
            app.MinpixelsizeSpinnerLabel = uilabel(app.UIFigure);
            app.MinpixelsizeSpinnerLabel.Text = 'Min pixel size';
            app.MinpixelsizeSpinner = uispinner(app.UIFigure);
            app.MinpixelsizeSpinner.Limits = [0 5000];
            app.MinpixelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinpixelsizeSpinnerValueChanged, true);
            app.MinpixelsizeSpinner.Value = 15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainiii

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end